classdef Glaucoma_diagnosis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UDIButton                  matlab.ui.control.Button
        ImagequalityLabel          matlab.ui.control.Label
        CroppedImageLabel          matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        SelectanimageButton        matlab.ui.control.Button
        TestwithrandomimageButton  matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImOr % Imagen original
        model %modelo de prediccion
        imdata %imagenes para random
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load Modelo_final_SVM.mat
            app.model=svm_final_mdl;
            load imagenes_limpias_predict.mat
            app.imdata=imlimpiaspredict;
        end

        % Button pushed function: TestwithrandomimageButton
        function Test(app, event)
            I = imagenlimpiarandom(app.imdata);
        
            % Mostrar la imagen en el UIAxes
            imshow(I, 'Parent', app.UIAxes);
            app.UIAxes.Visible=true;
            
            % Traer la ventana de la aplicación al frente
            figure(app.UIFigure);
            
            % Guardar la imagen cargada en una propiedad de la app
            app.ImOr = I; 
            
            %Pasar por el segmentador
            [~,~,~,~,cropped_image,~] = Bit_plane_slicing_segmentation(I);
            imshow(cropped_image, 'Parent', app.UIAxes_2);
            app.UIAxes_2.Visible=true;
        end

        % Button pushed function: SelectanimageButton
        function ImageSelected(app, event)
            % Mostrar diálogo para seleccionar la imagen
            [filename, pathname] = uigetfile({'*.jpg;*.png;', 'Imagenes (*.jpg, *.png)'}, 'Seleccionar Imagen');
            % Verificar si el usuario canceló la selección
            if isequal(filename, 0)
                figure(app.UIFigure);
                return; % No hacer nada si el usuario cancela la selección
            end
            % Concatenar la ruta completa del archivo
            fullpath = fullfile(pathname, filename);
            % Cargar la imagen seleccionada por el usuario
            I = imread(fullpath);
            
            %Sacar features y pasar por el modelo
            Features=one_image_features(I);
            [class, ~] = app.model.predictFcn(Features);
            
            if class == 4
                % Mostrar la imagen en el UIAxes
                imshow(I, 'Parent', app.UIAxes);
                app.UIAxes.Visible=true;
            
                % Traer la ventana de la aplicación al frente
                figure(app.UIFigure);
                
                % Guardar la imagen cargada en una propiedad de la app
                app.ImOr = I; 
    
                %Pasar por el segmentador
                [~,~,~,~,cropped_image,~] = Bit_plane_slicing_segmentation(I);
                imshow(cropped_image, 'Parent', app.UIAxes_2);
                app.UIAxes_2.Visible=true;
                
           elseif class==1
               app.ImagequalityLabel.Text="LOW QUALITY IMAGE \n   (LOW CONTRAST)";
               return;
           elseif class==2
               app.ImagequalityLabel.Text="LOW QUALITY IMAGE \n      (BLURRED)";
               return;
           elseif class==3
               app.ImagequalityLabel.Text="LOW QUALITY IMAGE \n        (NOISY)";
               return;
           end
        end

        % Button pushed function: UDIButton
        function UDIButtonPushed(app, event)
            Infoapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.Visible = 'off';
            app.TestwithrandomimageButton = uibutton(app.UIFigure, 'push');
            app.TestwithrandomimageButton.ButtonPushedFcn = createCallbackFcn(app, @Test, true);
            app.TestwithrandomimageButton.Text = 'Test with random image';
            app.SelectanimageButton = uibutton(app.UIFigure, 'push');
            app.SelectanimageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSelected, true);
            app.SelectanimageButton.Text = 'Select an image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.CroppedImageLabel = uilabel(app.UIFigure);
            app.CroppedImageLabel.Text = 'Cropped Image';
            app.ImagequalityLabel = uilabel(app.UIFigure);
            app.ImagequalityLabel.Text = 'Image quality';
            app.UDIButton = uibutton(app.UIFigure, 'push');
            app.UDIButton.ButtonPushedFcn = createCallbackFcn(app, @UDIButtonPushed, true);
            app.UDIButton.Text = {'UDI'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Glaucoma_diagnosis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end